classdef MovimientoDeProyectiles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        GoButton                        matlab.ui.control.Button
        AlturainicialmSpinnerLabel      matlab.ui.control.Label
        AlturainicialmSpinner           matlab.ui.control.Spinner
        VelocidadinicialmsSpinnerLabel  matlab.ui.control.Label
        VelocidadinicialmsSpinner       matlab.ui.control.Spinner
        ngulodelanzamientoSpinnerLabel  matlab.ui.control.Label
        ngulodelanzamientoSpinner       matlab.ui.control.Spinner
        MasadelobjetokgSpinnerLabel     matlab.ui.control.Label
        MasadelobjetokgSpinner          matlab.ui.control.Spinner
        ConstantedelaresistenciadelaireSpinnerLabel  matlab.ui.control.Label
        ConstantedelaresistenciadelaireSpinner  matlab.ui.control.Spinner
        TssLabel_2                      matlab.ui.control.Label
        TssSpinner                      matlab.ui.control.Spinner
        Densidaddelairekgm3SpinnerLabel  matlab.ui.control.Label
        Densidaddelairekgm3Spinner      matlab.ui.control.Spinner
        Seccindelreatransversalm2SpinnerLabel  matlab.ui.control.Label
        Seccindelreatransversalm2Spinner  matlab.ui.control.Spinner
        AlfaSpinnerLabel                matlab.ui.control.Label
        AlfaSpinner                     matlab.ui.control.Spinner
        AleatorioButton                 matlab.ui.control.Button
        oLabel                          matlab.ui.control.Label
        ConresistenciadelaireLabel      matlab.ui.control.Label
        SinresistenciadelaireLabel      matlab.ui.control.Label
        AlturainicialenYmLabel          matlab.ui.control.Label
        VelocidadinicialmsLabel         matlab.ui.control.Label
        AlturainicialenXmLabel          matlab.ui.control.Label
        ngulodelanzamientoLabel         matlab.ui.control.Label
        MasadelobjetokgLabel            matlab.ui.control.Label
        ConstantedelaresistenciadelaireLabel  matlab.ui.control.Label
        TssLabel                        matlab.ui.control.Label
        AltInicialY                     matlab.ui.control.Label
        AltInicialX                     matlab.ui.control.Label
        VelInicial                      matlab.ui.control.Label
        AngLanzam                       matlab.ui.control.Label
        MasaOb                          matlab.ui.control.Label
        ConsResisAire                   matlab.ui.control.Label
        AlturamximamLabel               matlab.ui.control.Label
        AlturamximamLabel_2             matlab.ui.control.Label
        PuntodecadamLabel               matlab.ui.control.Label
        PuntodecadamLabel_2             matlab.ui.control.Label
        PuntCaCA                        matlab.ui.control.Label
        PosicinenXmLabel                matlab.ui.control.Label
        PosicinenYmLabel                matlab.ui.control.Label
        VelocidadmsLabel                matlab.ui.control.Label
        PosXSA                          matlab.ui.control.Label
        PosYSA                          matlab.ui.control.Label
        VelSA                           matlab.ui.control.Label
        SinresistenciadelaireLabel_2    matlab.ui.control.Label
        PosicinenXmLabel_3              matlab.ui.control.Label
        PosicinenYmLabel_2              matlab.ui.control.Label
        VelocidadmsLabel_2              matlab.ui.control.Label
        PosYCA                          matlab.ui.control.Label
        VelCA                           matlab.ui.control.Label
        ConresistenciadelaireLabel_2    matlab.ui.control.Label
        PosXCA                          matlab.ui.control.Label
        AltMaxCA                        matlab.ui.control.Label
        PuntCaSA                        matlab.ui.control.Label
        Densidaddelairekgm3Label        matlab.ui.control.Label
        Seccindelreatransversalm2Label  matlab.ui.control.Label
        AlfaLabel                       matlab.ui.control.Label
        rhoLab                          matlab.ui.control.Label
        secTrA                          matlab.ui.control.Label
        alfaLab                         matlab.ui.control.Label
        AltMaxSa                        matlab.ui.control.Label
        TsLab                           matlab.ui.control.Label
        numLanz                         matlab.ui.control.Label
        proyectileslanzadosLabel        matlab.ui.control.Label
        TiempodevuelosLabel             matlab.ui.control.Label
        TiempVuelSA                     matlab.ui.control.Label
        TiempodevuelosLabel_2           matlab.ui.control.Label
        TiempVuelCA                     matlab.ui.control.Label
        SetomauntiempoparaquepuedaverlosresultadosLabel  matlab.ui.control.Label
        Nota                            matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [x_sin_aire, y_sin_aire, x_con_aire, y_con_aire, Vy_sin_aire, Vy_con_aire, Xo, t, h_max_sin_aire, h_max_con_aire] = Ops(app, Yo, Vo, Theta, masa, constante_resis, Ts, rho, A, alfa)
            
            %Trayectoria sin resistencia al aire:
            %Datos preestablecidos:
            Xo = 0;     %[m]
            g = -9.8;   %[m/s^2]
            
            %Datos calculados:
            theta_rad = Theta*pi/180;    %Theta en radianes
            
            Vo_x = Vo*cos(theta_rad); %Velocidad inicial en 'x' [m/s]
            Vo_y = Vo*sin(theta_rad); %Velocidad inicial en 'y' [m/s]
            
            t = (0:Ts:200);
            
            Kd = (1/2) * constante_resis * rho * A;
            
            Ado = (Kd/masa)*Vo^alfa;
            Ado_x = 0 - (Ado*cos(theta_rad));
            Ado_y = g - (Ado*sin(theta_rad));
            
            %Arreglos con posiciones
            x_sin_aire = zeros(1, length(t));
            y_sin_aire  = zeros(1, length(t));
            Vx_sin_aire  = zeros(1, length(t));
            Vy_sin_aire  = zeros(1, length(t));
            
            %Condiciones iniciales:
            x_sin_aire(1) = Xo;
            y_sin_aire(1) = Yo;
            Vx_sin_aire(1) = Vo_x;
            Vy_sin_aire(1) = Vo_y;
            
            %Se itera usando las fórmulas de Verlet sin resistencia al aire:
            for i = 2:length(x_sin_aire)
                % Vx
                Vx_sin_aire(i) = Vx_sin_aire(i-1) + (0*Ts);
                % Vy
                Vy_sin_aire(i) = Vy_sin_aire(i-1) + (g*Ts);
                % x
                x_sin_aire(i) = x_sin_aire(i-1) + (Vx_sin_aire(i-1)*Ts) + (((Ts^2)/2) * 0);
                % y
                y_sin_aire(i) = y_sin_aire(i-1) + (Vy_sin_aire(i-1)*Ts) + (((Ts^2)/2) * g);
            end
            
            %Altura máxima:
            h_max_sin_aire = max(y_sin_aire);
            
            
            %Trayectoria con resistencia al aire:
            %Arreglos con posiciones
            x_con_aire = zeros(1, length(t));
            y_con_aire = zeros(1, length(t));
            Vx_con_aire = zeros(1, length(t));
            Vy_con_aire = zeros(1, length(t));
            V_con_aire = zeros(1, length(t));
            Theta_vel_con_aire = zeros(1, length(t));
            Ax_con_aire = zeros(1, length(t));
            Ay_con_aire = zeros(1, length(t));
            
            %Condiciones iniciales:
            x_con_aire(1) = Xo;
            y_con_aire(1) = Yo;
            Vx_con_aire(1) = Vo_x;
            Vy_con_aire(1) = Vo_y;
            V_con_aire(1) = Vo;
            Theta_vel_con_aire(1) = theta_rad;
            Ax_con_aire(1) = Ado_x;
            Ay_con_aire(1) = Ado_y;
            
            %Se itera usando las fórmulas de Verlet con resistencia al aire:
            for i = 2:length(x_con_aire)
                % Ax
                Ax_con_aire(i) = 0 - ((Kd/masa)*(V_con_aire(i-1)^alfa)*cos(Theta_vel_con_aire(i-1)));
                % Ay
                Ay_con_aire(i) = g - ((Kd/masa)*(V_con_aire(i-1)^alfa)*sin(Theta_vel_con_aire(i-1)));
                % Vx
                Vx_con_aire(i) = Vx_con_aire(i-1) + ((Ax_con_aire(i-1)+Ax_con_aire(i))/2)*Ts;
                % Vy
                Vy_con_aire(i) = Vy_con_aire(i-1) + ((Ay_con_aire(i-1)+Ay_con_aire(i))/2)*Ts;
                % V
                V_con_aire(i) = ((Vy_con_aire(i)^2) + (Vx_con_aire(i)^2))^(1/2);
                % Theta
                Theta_vel_con_aire(i) = atan2(Vy_con_aire(i),Vx_con_aire(i));
                % y
                y_con_aire(i) = y_con_aire(i-1) + (Vy_con_aire(i-1)*Ts) + Ay_con_aire(i-1)/2*(Ts^2);
                % x
                x_con_aire(i) = x_con_aire(i-1) + (Vx_con_aire(i-1)*Ts) + Ax_con_aire(i-1)/2*(Ts^2);
            end
            
            %Altura máxima:
            h_max_con_aire = max(y_con_aire);
        end
        
        
        function Graph(app, x_sin_aire, y_sin_aire, x_con_aire, y_con_aire, Vy_sin_aire, Vy_con_aire, Xo, t, h_max_sin_aire, h_max_con_aire, Yo, Vo, Theta, masa, constante_resis, Ts, rho, A, alfa)
            
            %Graficar:
            a_con_aire = animatedline(app.UIAxes,'Color',[0.6350 0.0780 0.1840],'LineWidth',3);
            a_sin_aire = animatedline(app.UIAxes,'Color',[0.4940 0.1840 0.5560],'LineWidth',3);
            clearpoints(a_sin_aire);
            clearpoints(a_con_aire);
            legend(app.UIAxes, 'Con aire', 'Sin aire');
            for k = 1:length(x_con_aire)
                %Sin resistencia del aire
                if y_sin_aire(k) > -10
                    addpoints(a_sin_aire,x_sin_aire(k),y_sin_aire(k));
                    app.PosXSA.Text = num2str(x_sin_aire(k));
                    app.PosYSA.Text = num2str(y_sin_aire(k));
                    app.VelSA.Text = num2str(Vy_sin_aire(k));
                    app.TiempVuelSA.Text = num2str(t(k));
                end
                
                %Con resistencia del aire
                if y_con_aire(k) > -10
                    addpoints(a_con_aire,x_con_aire(k),y_con_aire(k));
                    app.PosXCA.Text = num2str(x_con_aire(k));
                    app.PosYCA.Text = num2str(y_con_aire(k));
                    app.VelCA.Text = num2str(Vy_con_aire(k));
                    app.TiempVuelCA.Text = num2str(t(k));
                end
                
                drawnow
            end
            app.AltInicialY.Text = num2str(Yo);
            app.AltInicialX.Text = num2str(Xo);
            app.VelInicial.Text = num2str(Vo);
            app.AngLanzam.Text = num2str(Theta);
            app.MasaOb.Text = num2str(masa);
            app.ConsResisAire.Text = num2str(constante_resis);
            app.TsLab.Text = num2str(Ts);
            app.rhoLab.Text = num2str(rho);
            app.secTrA.Text = num2str(A);
            app.alfaLab.Text = num2str(alfa);
            
            app.AltMaxSa.Text = num2str(h_max_sin_aire);
            app.AltMaxCA.Text = num2str(h_max_con_aire);
            
            app.PuntCaSA.Text = app.PosXSA.Text;
            app.PuntCaCA.Text = app.PosXCA.Text;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            cla(app.UIAxes)
            app.numLanz.Text = num2str(1);
            
            app.proyectileslanzadosLabel.Text = '/ 1 proyectil lanzado';
            
            Yo = app.AlturainicialmSpinner.Value;
            Vo = app.VelocidadinicialmsSpinner.Value;
            Theta = app.ngulodelanzamientoSpinner.Value;
            masa = app.MasadelobjetokgSpinner.Value;
            constante_resis = app.ConstantedelaresistenciadelaireSpinner.Value;
            Ts = app.TssSpinner.Value;
            rho = app.Densidaddelairekgm3Spinner.Value;
            A = app.Seccindelreatransversalm2Spinner.Value;
            alfa = app.AlfaSpinner.Value;
            
            [x_sin_aire, y_sin_aire, x_con_aire, y_con_aire, Vy_sin_aire, Vy_con_aire, Xo, t, h_max_sin_aire, h_max_con_aire] = Ops(app, Yo, Vo, Theta, masa, constante_resis, Ts, rho, A, alfa);
            
            Graph(app, x_sin_aire, y_sin_aire,x_con_aire,y_con_aire, Vy_sin_aire, Vy_con_aire, Xo, t, h_max_sin_aire, h_max_con_aire, Yo, Vo, Theta, masa, constante_resis, Ts, rho, A, alfa);
            
        end

        % Button pushed function: AleatorioButton
        function AleatorioButtonPushed(app, event)
            cla(app.UIAxes)
            app.numLanz.Text = num2str(0);
            
            i = 1;
            app.proyectileslanzadosLabel.Text = '/ 3 proyectiles lanzados';
            while i <= 3
                app.numLanz.Text = num2str(i);
                
                Yo = randi([0,1500],1);
                Vo = randi([50,250], 1);
                Theta = randi([0,90], 1);
                masa = randi([5,500], 1);
                constante_resis = 0.4 + rand*(1-0.4);
                Ts = 0.1 + rand*(0.5-0.1);
                rho = 1 + rand*(1.2-1);
                A = 0.5 + rand*(1-0.5);
                alfa = randi([1,2],1);
                
                [x_sin_aire, y_sin_aire, x_con_aire, y_con_aire, Vy_sin_aire, Vy_con_aire, Xo, t, h_max_sin_aire, h_max_con_aire] = Ops(app, Yo, Vo, Theta, masa, constante_resis, Ts, rho, A, alfa);
                
                Graph(app, x_sin_aire, y_sin_aire,x_con_aire,y_con_aire, Vy_sin_aire, Vy_con_aire, Xo, t, h_max_sin_aire, h_max_con_aire, Yo, Vo, Theta, masa, constante_resis, Ts, rho, A, alfa);
                
                pause(6);
                
                i = i + 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GoButton = uibutton(app.Panel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.AlturainicialmSpinnerLabel = uilabel(app.Panel);
            app.AlturainicialmSpinnerLabel.Text = 'Altura inicial [m]';
            app.AlturainicialmSpinner = uispinner(app.Panel);
            app.AlturainicialmSpinner.Step = 50;
            app.AlturainicialmSpinner.Limits = [0 1500];
            app.AlturainicialmSpinner.Value = 800;
            app.VelocidadinicialmsSpinnerLabel = uilabel(app.Panel);
            app.VelocidadinicialmsSpinnerLabel.Text = 'Velocidad inicial [m/s]';
            app.VelocidadinicialmsSpinner = uispinner(app.Panel);
            app.VelocidadinicialmsSpinner.Step = 5;
            app.VelocidadinicialmsSpinner.Limits = [50 250];
            app.VelocidadinicialmsSpinner.Value = 70;
            app.ngulodelanzamientoSpinnerLabel = uilabel(app.Panel);
            app.ngulodelanzamientoSpinnerLabel.Text = 'Ángulo de lanzamiento [º]';
            app.ngulodelanzamientoSpinner = uispinner(app.Panel);
            app.ngulodelanzamientoSpinner.Limits = [0 90];
            app.ngulodelanzamientoSpinner.Value = 55;
            app.MasadelobjetokgSpinnerLabel = uilabel(app.Panel);
            app.MasadelobjetokgSpinnerLabel.Text = 'Masa del objeto [kg]';
            app.MasadelobjetokgSpinner = uispinner(app.Panel);
            app.MasadelobjetokgSpinner.Step = 10;
            app.MasadelobjetokgSpinner.Limits = [5 500];
            app.MasadelobjetokgSpinner.Value = 70;
            app.ConstantedelaresistenciadelaireSpinnerLabel = uilabel(app.Panel);
            app.ConstantedelaresistenciadelaireSpinnerLabel.Text = 'Constante de la resistencia del aire';
            app.ConstantedelaresistenciadelaireSpinner = uispinner(app.Panel);
            app.ConstantedelaresistenciadelaireSpinner.Step = 0.1;
            app.ConstantedelaresistenciadelaireSpinner.Limits = [0.4 1];
            app.ConstantedelaresistenciadelaireSpinner.Value = 0.4;
            app.TssLabel_2 = uilabel(app.Panel);
            app.TssLabel_2.Text = 'Ts [s]';
            app.TssSpinner = uispinner(app.Panel);
            app.TssSpinner.Step = 0.1;
            app.TssSpinner.Limits = [0.1 0.5];
            app.TssSpinner.Value = 0.2;
            app.Densidaddelairekgm3SpinnerLabel = uilabel(app.Panel);
            app.Densidaddelairekgm3SpinnerLabel.Text = 'Densidad del aire [kg/m^3]';
            app.Densidaddelairekgm3Spinner = uispinner(app.Panel);
            app.Densidaddelairekgm3Spinner.Step = 0.01;
            app.Densidaddelairekgm3Spinner.Limits = [1 1.2];
            app.Densidaddelairekgm3Spinner.Value = 1.17;
            app.Seccindelreatransversalm2SpinnerLabel = uilabel(app.Panel);
            app.Seccindelreatransversalm2SpinnerLabel.Text = 'Sección del área transversal [m^2]';
            app.Seccindelreatransversalm2Spinner = uispinner(app.Panel);
            app.Seccindelreatransversalm2Spinner.Step = 0.1;
            app.Seccindelreatransversalm2Spinner.Limits = [0.5 1];
            app.Seccindelreatransversalm2Spinner.Value = 0.6;
            app.AlfaSpinnerLabel = uilabel(app.Panel);
            app.AlfaSpinnerLabel.Text = 'Alfa';
            app.AlfaSpinner = uispinner(app.Panel);
            app.AlfaSpinner.Limits = [1 2];
            app.AlfaSpinner.Value = 2;
            app.AleatorioButton = uibutton(app.Panel, 'push');
            app.AleatorioButton.ButtonPushedFcn = createCallbackFcn(app, @AleatorioButtonPushed, true);
            app.AleatorioButton.Text = 'Aleatorio!';
            app.oLabel = uilabel(app.Panel);
            app.oLabel.Text = 'o';
            app.ConresistenciadelaireLabel = uilabel(app.UIFigure);
            app.ConresistenciadelaireLabel.Text = 'Con resistencia del aire:';
            app.SinresistenciadelaireLabel = uilabel(app.UIFigure);
            app.SinresistenciadelaireLabel.Text = 'Sin resistencia del aire:';
            app.AlturainicialenYmLabel = uilabel(app.UIFigure);
            app.AlturainicialenYmLabel.Text = 'Altura inicial en Y [m]: ';
            app.VelocidadinicialmsLabel = uilabel(app.UIFigure);
            app.VelocidadinicialmsLabel.Text = 'Velocidad inicial [m/s]: ';
            app.AlturainicialenXmLabel = uilabel(app.UIFigure);
            app.AlturainicialenXmLabel.Text = 'Altura inicial en X [m]: ';
            app.ngulodelanzamientoLabel = uilabel(app.UIFigure);
            app.ngulodelanzamientoLabel.Text = 'Ángulo de lanzamiento  [º]: ';
            app.MasadelobjetokgLabel = uilabel(app.UIFigure);
            app.MasadelobjetokgLabel.Text = 'Masa del objeto [kg]: ';
            app.ConstantedelaresistenciadelaireLabel = uilabel(app.UIFigure);
            app.ConstantedelaresistenciadelaireLabel.Text = 'Constante de la resistencia del aire:';
            app.TssLabel = uilabel(app.UIFigure);
            app.TssLabel.Text = 'Ts [s]: ';
            app.AltInicialY = uilabel(app.UIFigure);
            app.AltInicialY.Text = '- - -';
            app.AltInicialX = uilabel(app.UIFigure);
            app.AltInicialX.Text = '- - -';
            app.VelInicial = uilabel(app.UIFigure);
            app.VelInicial.Text = '- - -';
            app.AngLanzam = uilabel(app.UIFigure);
            app.AngLanzam.Text = '- - -';
            app.MasaOb = uilabel(app.UIFigure);
            app.MasaOb.Text = '- - -';
            app.ConsResisAire = uilabel(app.UIFigure);
            app.ConsResisAire.Text = '- - -';
            app.AlturamximamLabel = uilabel(app.UIFigure);
            app.AlturamximamLabel.Text = 'Altura máxima [m]:';
            app.AlturamximamLabel_2 = uilabel(app.UIFigure);
            app.AlturamximamLabel_2.Text = 'Altura máxima [m]:';
            app.PuntodecadamLabel = uilabel(app.UIFigure);
            app.PuntodecadamLabel.Text = 'Punto de caída [m]:';
            app.PuntodecadamLabel_2 = uilabel(app.UIFigure);
            app.PuntodecadamLabel_2.Text = 'Punto de caída [m]:';
            app.PuntCaCA = uilabel(app.UIFigure);
            app.PuntCaCA.Text = '- - -';
            app.PosicinenXmLabel = uilabel(app.UIFigure);
            app.PosicinenXmLabel.Text = 'Posición en X [m]:';
            app.PosicinenYmLabel = uilabel(app.UIFigure);
            app.PosicinenYmLabel.Text = 'Posición en Y [m]:';
            app.VelocidadmsLabel = uilabel(app.UIFigure);
            app.VelocidadmsLabel.Text = 'Velocidad [m/s]:';
            app.PosXSA = uilabel(app.UIFigure);
            app.PosXSA.Text = '- - -';
            app.PosYSA = uilabel(app.UIFigure);
            app.PosYSA.Text = '- - -';
            app.VelSA = uilabel(app.UIFigure);
            app.VelSA.Text = '- - -';
            app.SinresistenciadelaireLabel_2 = uilabel(app.UIFigure);
            app.SinresistenciadelaireLabel_2.Text = 'Sin resistencia del aire:';
            app.PosicinenXmLabel_3 = uilabel(app.UIFigure);
            app.PosicinenXmLabel_3.Text = 'Posición en X [m]:';
            app.PosicinenYmLabel_2 = uilabel(app.UIFigure);
            app.PosicinenYmLabel_2.Text = 'Posición en Y [m]:';
            app.VelocidadmsLabel_2 = uilabel(app.UIFigure);
            app.VelocidadmsLabel_2.Text = 'Velocidad [m/s]:';
            app.PosYCA = uilabel(app.UIFigure);
            app.PosYCA.Text = '- - -';
            app.VelCA = uilabel(app.UIFigure);
            app.VelCA.Text = '- - -';
            app.ConresistenciadelaireLabel_2 = uilabel(app.UIFigure);
            app.ConresistenciadelaireLabel_2.Text = 'Con resistencia del aire:';
            app.PosXCA = uilabel(app.UIFigure);
            app.PosXCA.Text = '- - -';
            app.AltMaxCA = uilabel(app.UIFigure);
            app.AltMaxCA.Text = '- - -';
            app.PuntCaSA = uilabel(app.UIFigure);
            app.PuntCaSA.Text = '- - -';
            app.Densidaddelairekgm3Label = uilabel(app.UIFigure);
            app.Densidaddelairekgm3Label.Text = 'Densidad del aire [kg/m^3]:';
            app.Seccindelreatransversalm2Label = uilabel(app.UIFigure);
            app.Seccindelreatransversalm2Label.Text = 'Sección del área transversal [m^2]:';
            app.AlfaLabel = uilabel(app.UIFigure);
            app.AlfaLabel.Text = 'Alfa:';
            app.rhoLab = uilabel(app.UIFigure);
            app.rhoLab.Text = '- - -';
            app.secTrA = uilabel(app.UIFigure);
            app.secTrA.Text = '- - -';
            app.alfaLab = uilabel(app.UIFigure);
            app.alfaLab.Text = '- - -';
            app.AltMaxSa = uilabel(app.UIFigure);
            app.AltMaxSa.Text = '- - -';
            app.TsLab = uilabel(app.UIFigure);
            app.TsLab.Text = '- - -';
            app.numLanz = uilabel(app.UIFigure);
            app.numLanz.Text = '- - -';
            app.proyectileslanzadosLabel = uilabel(app.UIFigure);
            app.proyectileslanzadosLabel.Text = '/ 1 proyectiles lanzados';
            app.TiempodevuelosLabel = uilabel(app.UIFigure);
            app.TiempodevuelosLabel.Text = 'Tiempo de vuelo [s]: ';
            app.TiempVuelSA = uilabel(app.UIFigure);
            app.TiempVuelSA.Text = '- - -';
            app.TiempodevuelosLabel_2 = uilabel(app.UIFigure);
            app.TiempodevuelosLabel_2.Text = 'Tiempo de vuelo [s]: ';
            app.TiempVuelCA = uilabel(app.UIFigure);
            app.TiempVuelCA.Text = '- - -';
            app.SetomauntiempoparaquepuedaverlosresultadosLabel = uilabel(app.UIFigure);
            app.SetomauntiempoparaquepuedaverlosresultadosLabel.Text = '*Se toma un tiempo para que pueda ver los resultados.*';
            app.Nota = uilabel(app.UIFigure);
            app.Nota.Text = '*Se recomienda usar Alfa: 1 para objetos pequeños y Alfa: 2 para objetos grandes.*';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Movimiento de un proyectil con y sin aire')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 3000];
            app.UIAxes.YLim = [0 3000];
            app.UIAxes.XTick = [0 250 500 750 1000 1250 1500 1750 2000 2250 2500 2750 3000];
            app.UIAxes.XTickLabel = {'0'; '250'; '500'; '750'; '1000'; '1250'; '1500'; '1750'; '2000'; '2250'; '2500'; '2750'; '3000'};
            app.UIAxes.YTick = [0 250 500 750 1000 1250 1500 1750 2000 2250 2500 2750 3000];
            app.UIAxes.YTickLabel = {'0'; '250'; '500'; '750'; '1000'; '1250'; '1500'; '1750'; '2000'; '2250'; '2500'; '2750'; '3000'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MovimientoDeProyectiles

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end